classdef TestApplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DataMenu                      matlab.ui.container.Menu
        ExporttoworkspaceMenu         matlab.ui.container.Menu
        TransformdataMenu             matlab.ui.container.Menu
        SavetofileMenu                matlab.ui.container.Menu
        ImportattributesfromfileMenu  matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        DocumentationMenu             matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        RightPanel                    matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        SliderPanel                   matlab.ui.container.Panel
        UIHiddenSwitch                matlab.ui.control.Switch
        UIHiddenSwitchLabel           matlab.ui.control.Label
        DiscreteValuesSwitch          matlab.ui.control.Switch
        DiscreteValuesSwitchLabel     matlab.ui.control.Label
        ResetSlidersSwitch            matlab.ui.control.Switch
        ResetSlidersSwitchLabel       matlab.ui.control.Label
        HeadtrackingSwitch            matlab.ui.control.Switch
        HeadtrackingSwitchLabel       matlab.ui.control.Label
        StepsizeEditFieldLabel        matlab.ui.control.Label
        StepsizeEditField             matlab.ui.control.NumericEditField
        UpdateSlidersButton           matlab.ui.control.Button
        ShowHideTestUIButton          matlab.ui.control.Button
        SliderstartvaluesLabel        matlab.ui.control.Label
        GridLayout3                   matlab.ui.container.GridLayout
        LabelC                        matlab.ui.control.Label
        LabelB                        matlab.ui.control.Label
        LabelA                        matlab.ui.control.Label
        SliderC                       matlab.ui.control.Slider
        SliderA                       matlab.ui.control.Slider
        Label_2                       matlab.ui.control.Label
        SliderB                       matlab.ui.control.Slider
        Label                         matlab.ui.control.Label
        LeftPanel                     matlab.ui.container.Panel
        ExperimentLamp                matlab.ui.control.Lamp
        CloseServerButton             matlab.ui.control.Button
        SendAttributesButton          matlab.ui.control.Button
        ServerLamp                    matlab.ui.control.Lamp
        ServerStartButton             matlab.ui.control.Button
        FinishButton                  matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        PortEditField                 matlab.ui.control.NumericEditField
        PortEditFieldLabel            matlab.ui.control.Label
        IPAddressEditField            matlab.ui.control.EditField
        IPAddressEditFieldLabel       matlab.ui.control.Label
        ButtonRemove                  matlab.ui.control.Button
        ButtonAdd                     matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        ExperimentIDEditField         matlab.ui.control.EditField
        ExperimentIDEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
        TCPconnection      % Connection class handling communication
        IPAddress          % Server address
        Port               % Server port number
        ExperimentID       % ID for current experiment
        Attributes         % List of test attributes
        SliderStartValues  % Starting values of sliders
        ResetOnChange      % true = reset sliders
        DiscreteValues     % true = sliders have fixed step size
        SliderStepSize     % Step size for discrete values
        CurrentDirectory   % Current working directory of experiments
        EnableHeadtracking % true = save headtracking data
        ExperimentRunning  % true = experiment currently in progress
        UIHidden           % true = Test UI hidden
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TCPconnection = 0;
            app.UITable.Data = string;
            app.ServerLamp.Color = 'red';
            app.UIHidden = 1;
            app.EnableHeadtracking = 0;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %save settings
            app.ExperimentID = app.ExperimentIDEditField.Value;
            app.Attributes = app.UITable.Data;
            app.IPAddress = char(app.IPAddressEditField.Value);
            app.Port = app.PortEditField.Value;
            app.SliderStartValues = [app.SliderA.Value app.SliderB.Value app.SliderC.Value];
            app.ResetOnChange = app.ResetSlidersSwitch.Value;
            %app.DiscreteValues = app.DiscreteValuesSwitch.Value;
            app.UIHidden = 1;
            if strcmp(app.DiscreteValuesSwitch.Value, 'On')
                app.DiscreteValues = 1;
            else
                app.DiscreteValues = 0;
            end
            app.SliderStepSize = app.StepsizeEditField.Value;
            if app.SliderStepSize == 0
                app.SliderStepSize = 0.01;
            end
            
            %check Experiment ID
            if strcmp(app.ExperimentID, "")
                uialert(app.UIFigure, "Experiment ID cannot be empty!", "Error starting experiment");
                return;
            end
            
            %check connection
            if app.TCPconnection == 0 %connection closed or not started
                uialert(app.UIFigure, "Server is not started!", "Error starting experiment");
                return;
            end
            
            if app.TCPconnection.server.Connected == 0 %no client connected
                uialert(app.UIFigure, "No client connected!", "Error starting experiment");
                return;
            end
            
            %transmit startup data
            writeline(app.TCPconnection.server, "004|"+"A"+sprintf('%.3f', app.SliderStartValues(1)));
            pause(0.1);
            writeline(app.TCPconnection.server, "004|"+"B"+sprintf('%.3f', app.SliderStartValues(2)));
            pause(0.1);
            writeline(app.TCPconnection.server, "004|"+"C"+sprintf('%.3f', app.SliderStartValues(3)));
            pause(0.1);
            writeline(app.TCPconnection.server, "005|"+sprintf('%d', app.ResetOnChange));
            pause(0.1);
            writeline(app.TCPconnection.server, "006|"+sprintf('%d', app.DiscreteValues)+sprintf('%.3f', app.SliderStepSize));
            pause(0.1);
            %writeline(app.TCPconnection.server, "010|"+sprintf('%d', app.EnableHeadtracking));
            pause(0.1);
            for n = 1:length(app.UITable.Data)
                writeline(app.TCPconnection.server, "007|"+string(app.UITable.Data(n,:)));
                pause(0.1);
            end
            writeline(app.TCPconnection.server, "011|"+string(app.ExperimentID));
            
            %create folder
            dir = uigetdir('current working directory','Select Experiment folder');
            [status, msg, msgID] = mkdir(dir, app.ExperimentID);

            if status == 1
                if strcmp(msgID,'MATLAB:MKDIR:DirectoryExists')
                    uialert(app.UIFigure, "Directory " + dir + "\" + app.ExperimentID + " already exists.", msg);
                end
            else
                uialert(app.UIFigure, "Error creating directory.", "Error");
                resetData(app.TCPconnection);
                app.TCPconnection = 0;
            end
            app.CurrentDirectory = dir + "\" + app.ExperimentID;
            
            app.TCPconnection.test_id = app.ExperimentID;
            app.ExperimentLamp.Color = 'green';
            app.ExperimentRunning = 1;
        end

        % Menu selected function: ExporttoworkspaceMenu
        function ExporttoworkspaceMenuSelected(app, event)
            transformData(app.TCPconnection,app.UITable.Data);
            assignin("base","slider_data", app.TCPconnection.slider_data);
            assignin("base","slider_transformed_data", app.TCPconnection.slider_transformed_data);
            assignin("base", "headtracking_data", app.TCPconnection.headtracking_data);
        end

        % Button pushed function: ButtonAdd
        function ButtonAddPushed(app, event)
            app.UITable.Data(end+1,:) = {''};
        end

        % Button pushed function: ButtonRemove
        function ButtonRemovePushed(app, event)
            app.UITable.Data(end,:) = [];
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            writeline(app.TCPconnection.server, "008|1");
            writeline(app.TCPconnection.server, "009|");
            writeline(app.TCPconnection.server, "015|");
            %lead headtracking file%
            transformData(app.TCPconnection,app.UITable.Data);
            assignin("base","slider_data", app.TCPconnection.slider_data);
            assignin("base","slider_transformed_data", app.TCPconnection.slider_transformed_data);
            assignin("base", "headtracking_data", app.TCPconnection.headtracking_data);
            save(app.CurrentDirectory + "\" + app.ExperimentID)
            writetable(app.TCPconnection.slider_transformed_data, app.CurrentDirectory + "\" + app.ExperimentID + ".csv",...
                'WriteVariableNames',1);
            writetable(app.TCPconnection.headtracking_data, app.CurrentDirectory + "\" + "headtracking.csv",...
                'Delimiter', ' ', "WriteVariableNames",1);
            assignin("base", "time", app.TCPconnection.headtracking_time);
            assignin("base", "speed", app.TCPconnection.tracking_speed);
            fid = fopen(app.CurrentDirectory+"\"+"Info.txt", 'wt');
            fprintf(fid, '%s\n', "Experiment ID: " + app.ExperimentID);
            fprintf(fid, '%s\n', "HMD enabled/detected: " + app.TCPconnection.hmd_enabled);
            fprintf(fid, '%s\n', "Headtracking speed: " + app.TCPconnection.tracking_speed + "s");
            fprintf(fid, '%s\n', "Headtracking start time: " + app.TCPconnection.headtracking_time);
            fclose(fid);
            app.ExperimentLamp.Color = 'red';
            app.ExperimentRunning = 0;
        end

        % Callback function
        function ResetslidersonscenechangeCheckBoxValueChanged(app, event)
            value = app.ResetslidersonscenechangeCheckBox.Value;
            app.ResetOnChange = value;
        end

        % Callback function
        function DiscreteslidervaluesCheckBoxValueChanged(app, event)
            value = app.DiscreteslidervaluesCheckBox.Value;
            app.DiscreteValues = value;
            if value == 1
                app.SliderStepSize = app.StepsizeEditField.Value;
            end
        end

        % Value changed function: SliderA
        function SliderAValueChanged(app, event)
            value = app.SliderA.Value;
            app.SliderStartValues(1) = value;
        end

        % Value changed function: SliderB
        function SliderBValueChanged(app, event)
            value = app.SliderB.Value;
            app.SliderStartValues(2) = value;
        end

        % Value changed function: SliderC
        function SliderCValueChanged(app, event)
            value = app.SliderC.Value;
            app.SliderStartValues(3) = value;
        end

        % Callback function
        function SliderDValueChanged(app, event)
            value = app.SliderD.Value;
            app.SliderStartValues(4) = value;
        end

        % Menu selected function: ImportattributesfromfileMenu
        function ImportattributesfromfileMenuSelected(app, event)
            [file, path] = uigetfile('Select file');

            if isequal(file, 0)
                return;
            else
                filename = path + "\" + file;
                app.UITable.Data = importdata(filename);
            end
        end

        % Button pushed function: ServerStartButton
        function ServerStartButtonPushed(app, event)
            %establish connection
            app.TCPconnection = Connection(app.IPAddressEditField.Value, app.PortEditField.Value);
            
            app.ServerLamp.Color = 'green';
        end

        % Button pushed function: ShowHideTestUIButton
        function ShowHideTestUIButtonPushed(app, event)
            if app.TCPconnection == 0
                uialert(app.UIFigure, "Server not started!", "Error showing TestUI");
                return;
            end
            writeline(app.TCPconnection.server, "008|");
        end

        % Menu selected function: SavetofileMenu
        function SavetofileMenuSelected(app, event)
            if app.TCPconnection == 0
                uialert(app.UIFigure, "Server not started!", "Error saving data");
                return;
            end
            transformData(app.TCPconnection, app.UITable.Data);
            assignin("base","slider_data", app.TCPconnection.slider_data);
            assignin("base","slider_transformed_data", app.TCPconnection.slider_transformed_data);
            assignin("base", "headtracking_data", app.TCPconnection.headtracking_data);
            save(app.CurrentDirectory + "\" + app.ExperimentID)
            writetable(app.TCPconnection.slider_transformed_data, app.CurrentDirectory + "\" + app.ExperimentID + ".csv",...
                'WriteVariableNames',1);
            writetable(app.TCPconnection.headtracking_data, app.CurrentDirectory + "\" + "headtracking.csv",...
                "WriteVariableNames",1);
        end

        % Menu selected function: TransformdataMenu
        function TransformdataMenuSelected(app, event)
            transformData(app.TCPconnection,app.UITabe.Data);
        end

        % Value changed function: ResetSlidersSwitch
        function ResetSlidersSwitchValueChanged(app, event)
            value = app.ResetSlidersSwitch.Value;         
            if strcmp(value, 'On')
                app.ResetOnChange = 1;
            else
                app.ResetOnChange = 0;
            end
        end

        % Value changed function: DiscreteValuesSwitch
        function DiscreteValuesSwitchValueChanged(app, event)
            value = app.DiscreteValuesSwitch.Value;
            
            if strcmp(value,'On')
                app.DiscreteValues = 1;
                app.SliderStepSize = app.StepsizeEditField.Value;
            else
                app.DiscreteValues = 0;
            end
        end

        % Value changed function: HeadtrackingSwitch
        function HeadtrackingSwitchValueChanged(app, event)
            value = app.HeadtrackingSwitch.Value;
        
            if strcmp(value, 'On')
                app.EnableHeadtracking = 1;
            else
                app.EnableHeadtracking = 0;
            end
            
            if app.ExperimentRunning
                writeline(app.TCPconnection.server, "010|"+sprintf('%d', app.EnableHeadtracking));
            end
        end

        % Button pushed function: UpdateSlidersButton
        function UpdateSlidersButtonPushed(app, event)
            if not(app.ExperimentRunning)
                return;
            end
            app.SliderStepSize = app.StepsizeEditField.Value;
            writeline(app.TCPconnection.server, "004|"+"A"+sprintf('%.3f', app.SliderStartValues(1)));
            pause(0.1);
            writeline(app.TCPconnection.server, "004|"+"B"+sprintf('%.3f', app.SliderStartValues(2)));
            pause(0.1);
            writeline(app.TCPconnection.server, "004|"+"C"+sprintf('%.3f', app.SliderStartValues(3)));
            pause(0.1);
            writeline(app.TCPconnection.server, "005|"+sprintf('%d', app.ResetOnChange));
            pause(0.1);
            writeline(app.TCPconnection.server, "006|"+sprintf('%d', app.DiscreteValues)+sprintf('%.3f', app.SliderStepSize));
            pause(0.1);
        end

        % Button pushed function: SendAttributesButton
        function SendAttributesButtonPushed(app, event)
            if not(app.ExperimentRunning)
                return
            end
            for n = 1:length(app.UITable.Data)
                writeline(app.TCPconnection.server, "007|"+string(app.UITable.Data(n,:)));
                pause(0.1);
            end
        end

        % Button pushed function: CloseServerButton
        function CloseServerButtonPushed(app, event)
            transformData(app.TCPconnection, app.UITable.Data);
            assignin("base","slider_data", app.TCPconnection.slider_data);
            assignin("base","slider_transformed_data", app.TCPconnection.slider_transformed_data);
            assignin("base", "headtracking_data", app.TCPconnection.headtracking_data);
            app.TCPconnection = 0;
            app.ServerLamp.Color = 'red';
        end

        % Menu selected function: DocumentationMenu
        function DocumentationMenuSelected(app, event)
            web('\\verdi\exchange\wegener\UnrealTCP\Documentation\documentation.html');
        end

        % Value changed function: UIHiddenSwitch
        function UIHiddenSwitchValueChanged(app, event)
            value = app.UIHiddenSwitch.Value;
            if not(app.ExperimentRunning)
                app.UIHiddenSwitch.Value = 'On';
                return;
            end
            
            if strcmp(value, 'On')
                app.UIHidden = 1;
                writeline(app.TCPconnection.server, "008|1");
            else
                app.UIHidden = 0;
                writeline(app.TCPconnection.server, "008|0");
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataMenu = uimenu(app.UIFigure);
            app.DataMenu.Text = 'Data';
            app.ExporttoworkspaceMenu = uimenu(app.DataMenu);
            app.ExporttoworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoworkspaceMenuSelected, true);
            app.ExporttoworkspaceMenu.Text = 'Export to workspace';
            app.TransformdataMenu = uimenu(app.DataMenu);
            app.TransformdataMenu.MenuSelectedFcn = createCallbackFcn(app, @TransformdataMenuSelected, true);
            app.TransformdataMenu.Text = 'Transform data';
            app.SavetofileMenu = uimenu(app.DataMenu);
            app.SavetofileMenu.MenuSelectedFcn = createCallbackFcn(app, @SavetofileMenuSelected, true);
            app.SavetofileMenu.Text = 'Save to file';
            app.ImportattributesfromfileMenu = uimenu(app.DataMenu);
            app.ImportattributesfromfileMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportattributesfromfileMenuSelected, true);
            app.ImportattributesfromfileMenu.Text = 'Import attributes from file';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationMenuSelected, true);
            app.DocumentationMenu.Text = 'Documentation';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ExperimentIDEditFieldLabel = uilabel(app.LeftPanel);
            app.ExperimentIDEditFieldLabel.Text = 'Experiment ID';
            app.ExperimentIDEditField = uieditfield(app.LeftPanel, 'text');
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Attributes'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.ButtonAdd = uibutton(app.LeftPanel, 'push');
            app.ButtonAdd.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddPushed, true);
            app.ButtonAdd.Text = '+';
            app.ButtonRemove = uibutton(app.LeftPanel, 'push');
            app.ButtonRemove.ButtonPushedFcn = createCallbackFcn(app, @ButtonRemovePushed, true);
            app.ButtonRemove.Text = '-';
            app.IPAddressEditFieldLabel = uilabel(app.LeftPanel);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.LeftPanel, 'text');
            app.IPAddressEditField.Value = 'localHost';
            app.PortEditFieldLabel = uilabel(app.LeftPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PortEditField.Value = 4000;
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.FinishButton = uibutton(app.LeftPanel, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.ServerStartButton = uibutton(app.LeftPanel, 'push');
            app.ServerStartButton.ButtonPushedFcn = createCallbackFcn(app, @ServerStartButtonPushed, true);
            app.ServerStartButton.Text = 'Server Start';
            app.ServerLamp = uilamp(app.LeftPanel);
            app.SendAttributesButton = uibutton(app.LeftPanel, 'push');
            app.SendAttributesButton.ButtonPushedFcn = createCallbackFcn(app, @SendAttributesButtonPushed, true);
            app.SendAttributesButton.Text = 'Send Attributes';
            app.CloseServerButton = uibutton(app.LeftPanel, 'push');
            app.CloseServerButton.ButtonPushedFcn = createCallbackFcn(app, @CloseServerButtonPushed, true);
            app.CloseServerButton.Text = 'Close Server';
            app.ExperimentLamp = uilamp(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '';
            app.SliderB = uislider(app.GridLayout3);
            app.SliderB.Limits = [0 1];
            app.SliderB.ValueChangedFcn = createCallbackFcn(app, @SliderBValueChanged, true);
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = '';
            app.SliderA = uislider(app.GridLayout3);
            app.SliderA.Limits = [0 1];
            app.SliderA.ValueChangedFcn = createCallbackFcn(app, @SliderAValueChanged, true);
            app.SliderC = uislider(app.GridLayout3);
            app.SliderC.Limits = [0 1];
            app.SliderC.ValueChangedFcn = createCallbackFcn(app, @SliderCValueChanged, true);
            app.LabelA = uilabel(app.GridLayout3);
            app.LabelA.Text = 'A';
            app.LabelB = uilabel(app.GridLayout3);
            app.LabelB.Text = 'B';
            app.LabelC = uilabel(app.GridLayout3);
            app.LabelC.Text = 'C';
            app.SliderstartvaluesLabel = uilabel(app.GridLayout2);
            app.SliderstartvaluesLabel.Text = 'Slider start values';
            app.SliderPanel = uipanel(app.GridLayout2);
            app.ShowHideTestUIButton = uibutton(app.SliderPanel, 'push');
            app.ShowHideTestUIButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHideTestUIButtonPushed, true);
            app.ShowHideTestUIButton.Text = 'Show/Hide TestUI';
            app.UpdateSlidersButton = uibutton(app.SliderPanel, 'push');
            app.UpdateSlidersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSlidersButtonPushed, true);
            app.UpdateSlidersButton.Text = 'Update Sliders';
            app.StepsizeEditField = uieditfield(app.SliderPanel, 'numeric');
            app.StepsizeEditField.Limits = [0 1];
            app.StepsizeEditFieldLabel = uilabel(app.SliderPanel);
            app.StepsizeEditFieldLabel.Text = 'Step size';
            app.HeadtrackingSwitchLabel = uilabel(app.SliderPanel);
            app.HeadtrackingSwitchLabel.Text = 'Headtracking';
            app.HeadtrackingSwitch = uiswitch(app.SliderPanel, 'slider');
            app.HeadtrackingSwitch.ValueChangedFcn = createCallbackFcn(app, @HeadtrackingSwitchValueChanged, true);
            app.ResetSlidersSwitchLabel = uilabel(app.SliderPanel);
            app.ResetSlidersSwitchLabel.Text = 'Reset Sliders';
            app.ResetSlidersSwitch = uiswitch(app.SliderPanel, 'slider');
            app.ResetSlidersSwitch.ValueChangedFcn = createCallbackFcn(app, @ResetSlidersSwitchValueChanged, true);
            app.DiscreteValuesSwitchLabel = uilabel(app.SliderPanel);
            app.DiscreteValuesSwitchLabel.Text = 'Discrete Values';
            app.DiscreteValuesSwitch = uiswitch(app.SliderPanel, 'slider');
            app.DiscreteValuesSwitch.ValueChangedFcn = createCallbackFcn(app, @DiscreteValuesSwitchValueChanged, true);
            app.UIHiddenSwitchLabel = uilabel(app.SliderPanel);
            app.UIHiddenSwitchLabel.Text = 'UI Hidden';
            app.UIHiddenSwitch = uiswitch(app.SliderPanel, 'slider');
            app.UIHiddenSwitch.ValueChangedFcn = createCallbackFcn(app, @UIHiddenSwitchValueChanged, true);
            app.UIHiddenSwitch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestApplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end